classdef AdAnalyser_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                 matlab.ui.Figure
        saveSettings        matlab.ui.control.Button
        loadSettings        matlab.ui.control.Button
        mainGUI             matlab.ui.container.Panel
        displayEDA          matlab.ui.control.ListBox
        displayEEG          matlab.ui.control.ListBox
        text3               matlab.ui.control.Label
        browseEDA           matlab.ui.control.Button
        browseEEG           matlab.ui.control.Button
        textStimuIntDir     matlab.ui.control.Label
        displayStimuIntDir  matlab.ui.control.EditField
        browseStimuIntDir   matlab.ui.control.Button
        displayHRV          matlab.ui.control.ListBox
        text13              matlab.ui.control.Label
        browseHRV           matlab.ui.control.Button
        displayOutputDir    matlab.ui.control.EditField
        browseOuputDir      matlab.ui.control.Button
        text15              matlab.ui.control.Label
        textOutputDir       matlab.ui.control.Label
        DisplayVideoDir     matlab.ui.control.EditField
        browseVideoDir      matlab.ui.control.Button
        textVideoDir        matlab.ui.control.Label
        settings            matlab.ui.control.Button
        mainFunction        matlab.ui.container.Panel
        analyse             matlab.ui.control.Button
        prepare             matlab.ui.control.Button
        filter              matlab.ui.control.Button
        sideFunction        matlab.ui.container.Panel
        BrainMap            matlab.ui.control.Button
        MomentaryFreq       matlab.ui.control.Button
        SettingsBorder      matlab.ui.container.Panel
    end

    % This is the Main GUI of the AdAnalyser.

    % Here the user can define the different paths for the stimulus, the 
    % output or the video. In addition, the data of the experiment are imported here. 
    % With Load and Save Settings the user can create and reopen presets.
    % Further setting options can be accessed under the "Settings" button.
    
    % All icons could be found under:
    % https://fonts.google.com/icons 
    
    % Naming convention for the GUI
    % Edit Filed = displayName
    % button = name
    % Browse button = browseName
    % text = textName
    % checkboxes = boxName
    
    methods (Access = private)
        %function to call the Electrodes Dialog GUI
        function electrodesSettings_Callback(app)
            ElectrodesDialog;
        end
        
        %Delete if no erros accurce
        %function lowerEEGThreshold_CreateFcn(app, hObject)
        %    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %        set(hObject,'BackgroundColor','white');
        %    end
        %end
        
        %function qualityIndex_CreateFcn(app, hObject)
        %    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %        set(hObject,'BackgroundColor','white');
        %    end
        %end
        
        %function upperEEGThreshold_CreateFcn(app, hObject)
        %    if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %        set(hObject,'BackgroundColor','white');
        %    end
        %end 
        
        %updates UI window if called
        function updateUI(app)
            global conf;
            app.displayOutputDir.Value = conf.OutputDirectory;
            app.DisplayVideoDir.Value = conf.videoString;
            app.displayStimuIntDir.Value = conf.StimuIntDef;
            app.displayEDA.Items= conf.EDAFiles;
            app.displayEEG.Items = conf.EEGFiles;
            app.displayHRV.Items = conf.HRVFiles;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AdAnalyser_OpeningFcn(app)
            %opens main GUI and centers it
            app.GUI.Visible = 'off';
            movegui(app.GUI,"center");
            app.GUI.Visible = 'on';
            global conf
            global configManager
            conf = Config;
            configManager = ConfigManager;
        end

        % Value changed function: displayStimuIntDir
        function displayStimuIntDir_Callback(app, event)
            %prints the choosen directory for Stimulus
            global conf
            input = get(hObject,'String');
            conf.StimuIntDef = input;
        end

        % Button pushed function: analyse
        function analyse_Callback(app, event)
            %Callback for Analyse Button
            %starts the Analyse Action
            global conf;
            global data;
            global eegdevice edadevice hrvdevice;
            fprintf('\nAnalyse all data sets');
            %initialize the AnalyseAction.m code
            a = AnalyseAction();
            a.analyse(data,conf,eegdevice,edadevice,hrvdevice);
            fprintf('\nDone\n');
            % activate next button
            app.BrainMap.Enable = 'on';
            app.MomentaryFreq.Enable = 'on';
            
            app.analyse.BackgroundColor = 'white';
        end

        % Button pushed function: browseEDA
        function browseEDA_Callback(app, event)
            %function to browse the EDA Files Button
            %user can choose EDA Files from browser window and GUI gets
            %updated accordingly
            global conf;
            %get file path/name
            [filenames, pathname] = uigetfile('.\data\*.txt;*.csv', 'Select EDA files','MultiSelect', 'on');
            empty = isempty(pathname) || pathname(1:1)==0;
            cutOffString = strfind(pathname,'\data');
            pathname = strcat('.',pathname(cutOffString:end));
            if empty==0
                if ~iscell(filenames) && ischar(filenames)
                    filenames = {filenames}; % force it to be a cell array of strings
                end
                edaFilesName = cell(size(filenames));
                for file = 1:numel(filenames)
                    fullpath = fullfile(pathname,filenames{file});
                    edaFilesName = [edaFilesName, fullpath];
                end
                edaFilesName(cellfun('isempty',edaFilesName)) = [];
                conf.EDAFiles = edaFilesName; %save Filenames in conf
                app.displayEDA.Items = conf.EDAFiles; %display
                figure(app.GUI) %update GUI
            end
        end

        % Button pushed function: browseEEG
        function browseEEG_Callback(app, event)
            %function to browse the EEG Files Button
            %see browseEDA_Callback
            global conf;
            [filenames, pathname] = uigetfile('.\data\*.txt;*.csv', 'Select EEG files','MultiSelect', 'on');
            empty = isempty(pathname) || pathname(1:1)==0;
            cutOffString = strfind(pathname,'\data');
            pathname = strcat('.',pathname(cutOffString:end));
            if empty==0
                if ~iscell(filenames) && ischar(filenames)
                    filenames = {filenames};
                end
                eegFilesNames = cell(size(filenames));
                for file = 1:numel(filenames)
                    fullpath = fullfile(pathname,filenames{file});
                    eegFilesNames = [eegFilesNames, fullpath];
                end
                eegFilesNames(cellfun('isempty',eegFilesNames)) = [];
                conf.EEGFiles = eegFilesNames;
                app.displayEEG.Items = conf.EEGFiles;
                figure(app.GUI)
            end
        end

        % Button pushed function: browseHRV
        function browseHRV_Callback(app, event)
            %function to browse the HRV Files Button
            %see browseEDA_Callback
            global conf;
            [filenames, pathname] = uigetfile('.\data\*.txt;*.csv', 'Select HRV files','MultiSelect', 'on');
            empty = isempty(pathname) || pathname(1:1)==0;
            cutOffString = strfind(pathname,'\data');
            pathname = strcat('.',pathname(cutOffString:end));
            if empty==0
                if ~iscell(filenames) && ischar(filenames)
                    filenames = {filenames};
                end
                hrvFilesNames = cell(size(filenames));
                for file = 1:numel(filenames)
                    fullpath = fullfile(pathname,filenames{file});
                    hrvFilesNames = [hrvFilesNames, fullpath];
                end
                hrvFilesNames(cellfun('isempty',hrvFilesNames)) = [];
                conf.HRVFiles = hrvFilesNames;
                app.displayHRV.Items = conf.HRVFiles;
                figure(app.GUI)
            end
        end

        % Button pushed function: browseOuputDir
        function browseOutput_Callback(app, event)
            %function to determind the Output directory
            global conf;
            outputDirectoryName=uigetdir(".\",'Choose an Output Directory');
            cutOffString = strfind(outputDirectoryName,'\output');
            outputDirectoryName = strcat('.',outputDirectoryName(cutOffString:end));
            if outputDirectoryName~=0
                conf.OutputDirectory=outputDirectoryName;
                app.displayOutputDir.Value = conf.OutputDirectory;
                figure(app.GUI)
            end
        end

        % Button pushed function: browseStimuIntDir
        function browseStimuIntDir_Callback(app, event)
            %function to import the AdIndex file
            global conf;
            [filename, pathname]=uigetfile('.\config\AdIndex\*.txt;*.csv','Select a File with SimulationsInterval');
            cutOffString = strfind(pathname,'\config');
            pathname = strcat('.',pathname(cutOffString:end));
            if filename~=0
                conf.StimuIntDef = fullfile(pathname, filename);
                app.displayStimuIntDir.Value = conf.StimuIntDef;
                figure(app.GUI)
            end
        end

        % Button pushed function: filter
        function filter_Callback(app, event)
            %Callback for Filter Button
            %starts the Filter Action
            global conf
            global data
            global eegdevice edadevice hrvdevice;
            fprintf('\nFilter subjects');
            %initialize the FilterAction.m code
            filterClass = FilterAction();
            [data,conf] = filterClass.filter(data,conf,eegdevice,edadevice,hrvdevice);
            app.analyse.Enable = 'on';
            app.filter.BackgroundColor = 'white';
            fprintf('\nDone\n');
        end

        % Button pushed function: loadSettings
        function loadSettings_Callback(app, event)
            %Callback for the load settings button
            %Imports a save preset and updates GUI 
            global conf;
            global configManager;
            global eegdevice edadevice hrvdevice;
            %graps data from variable conf and updates GUI
            [conf,eegdevice,edadevice,hrvdevice] = configManager.load(conf);
            updateUI(app);
            figure(app.GUI)
            app.prepare.BackgroundColor = [0.80,0.80,0.80];
                        
            app.filter.Enable = 'off';
            app.filter.BackgroundColor = [0.80,0.80,0.80];
            
            app.analyse.Enable = 'off';
            app.analyse.BackgroundColor = [0.80,0.80,0.80];
            
            app.BrainMap.Enable = 'off';
            app.MomentaryFreq.Enable = 'off';
        end

        % Value changed function: displayOutputDir
        function outputDir_Callback(app, event)
            global conf
            input = get(hObject,'String');
            conf.OutputDirectory = input;
        end

        % Button pushed function: prepare
        function prepare_Callback(app, event)
            %Callback for Prepare Button
            %starts the prepare Action
            global conf;
            global data;
            global eegdevice edadevice hrvdevice;
            fprintf('\nPrepare subjects\n\n');
            %initialize the PrepareAction.m code
            app.analyse.Enable = 0;
            app.BrainMap.Enable = 0;
            app.MomentaryFreq.Enable = 0;
            action = PrepareAction();
            [data,conf] = action.prepare(conf,eegdevice,edadevice,hrvdevice);
            if (data.isValid)
                fprintf('\nDone\n');
                app.filter.Enable = 'on';
                app.filter.BackgroundColor = [0.80,0.80,0.80];
                
                app.analyse.Enable = 'off';
                app.analyse.BackgroundColor = [0.80,0.80,0.80];
                
                app.prepare.BackgroundColor = 'white';
            end
        end

        % Button pushed function: saveSettings
        function saveSettings_Callback(app, event)
            %Callback for Save Settings Button
            global conf;
            global configManager
            configManager.save(conf); %Save settings in conf
            figure(app.GUI)
        end

        % Button pushed function: settings
        function settings_Callback(app, event)
            ConfigDialog_App;
        end

        % Button pushed function: browseVideoDir
        function browseVideoDirButtonPushed(app, event)
            %function to get the video file name
            global conf;
            dname = uigetdir('.\data','Select the Video Directory');
            cutOffString = strfind(dname,'\data');
            dname = strcat('.',dname(cutOffString:end));
            if dname~=0
                conf.videoString = dname;
                app.DisplayVideoDir.Value = conf.videoString; 
                figure(app.GUI)
            end
        end

        % Callback function
        function exportButtonPushed(app, event)
            ExportDialog_App;
        end

        % Button pushed function: BrainMap
        function BrainMapButtonPushed(app, event)
            BrainMapDialog;
        end

        % Button pushed function: MomentaryFreq
        function MomentaryFreqButtonPushed(app, event)
            MomentaryFreqDialog;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'AdAnalyser';
            app.saveSettings = uibutton(app.GUI, 'push');
            app.saveSettings.ButtonPushedFcn = createCallbackFcn(app, @saveSettings_Callback, true);
            app.saveSettings.Icon = 'outline_save_black_48dp.png';
            app.saveSettings.Text = 'Save';
            app.loadSettings = uibutton(app.GUI, 'push');
            app.loadSettings.ButtonPushedFcn = createCallbackFcn(app, @loadSettings_Callback, true);
            app.loadSettings.Icon = 'outline_file_download_black_48dp.png';
            app.loadSettings.Text = 'Load';
            app.mainGUI = uipanel(app.GUI);
            app.mainGUI.Title = 'Directory Settings';
            app.displayEDA = uilistbox(app.mainGUI);
            app.displayEDA.Items = {};
            app.displayEDA.Value = {};
            app.displayEEG = uilistbox(app.mainGUI);
            app.displayEEG.Items = {};
            app.displayEEG.Value = {};
            app.text3 = uilabel(app.mainGUI);
            app.text3.Text = 'EEG Files';
            app.browseEDA = uibutton(app.mainGUI, 'push');
            app.browseEDA.ButtonPushedFcn = createCallbackFcn(app, @browseEDA_Callback, true);
            app.browseEDA.Icon = 'baseline_add_black_48dp.png';
            app.browseEDA.Text = 'Browse';
            app.browseEEG = uibutton(app.mainGUI, 'push');
            app.browseEEG.ButtonPushedFcn = createCallbackFcn(app, @browseEEG_Callback, true);
            app.browseEEG.Icon = 'baseline_add_black_48dp.png';
            app.browseEEG.Text = 'Browse';
            app.textStimuIntDir = uilabel(app.mainGUI);
            app.textStimuIntDir.Text = 'Stimulus Directory';
            app.displayStimuIntDir = uieditfield(app.mainGUI, 'text');
            app.displayStimuIntDir.ValueChangedFcn = createCallbackFcn(app, @displayStimuIntDir_Callback, true);
            app.browseStimuIntDir = uibutton(app.mainGUI, 'push');
            app.browseStimuIntDir.ButtonPushedFcn = createCallbackFcn(app, @browseStimuIntDir_Callback, true);
            app.browseStimuIntDir.Icon = 'baseline_add_black_48dp.png';
            app.browseStimuIntDir.Text = 'Browse';
            app.displayHRV = uilistbox(app.mainGUI);
            app.displayHRV.Items = {};
            app.displayHRV.Value = {};
            app.text13 = uilabel(app.mainGUI);
            app.text13.Text = 'HRV Files';
            app.browseHRV = uibutton(app.mainGUI, 'push');
            app.browseHRV.ButtonPushedFcn = createCallbackFcn(app, @browseHRV_Callback, true);
            app.browseHRV.Icon = 'baseline_add_black_48dp.png';
            app.browseHRV.Text = 'Browse';
            app.displayOutputDir = uieditfield(app.mainGUI, 'text');
            app.displayOutputDir.ValueChangedFcn = createCallbackFcn(app, @outputDir_Callback, true);
            app.browseOuputDir = uibutton(app.mainGUI, 'push');
            app.browseOuputDir.ButtonPushedFcn = createCallbackFcn(app, @browseOutput_Callback, true);
            app.browseOuputDir.Icon = 'baseline_add_black_48dp.png';
            app.browseOuputDir.Text = 'Browse';
            app.text15 = uilabel(app.mainGUI);
            app.text15.Text = 'EDA Files';
            app.textOutputDir = uilabel(app.mainGUI);
            app.textOutputDir.Text = 'Output Directory';
            app.DisplayVideoDir = uieditfield(app.mainGUI, 'text');
            app.browseVideoDir = uibutton(app.mainGUI, 'push');
            app.browseVideoDir.ButtonPushedFcn = createCallbackFcn(app, @browseVideoDirButtonPushed, true);
            app.browseVideoDir.Icon = 'baseline_add_black_48dp.png';
            app.browseVideoDir.Text = 'Browse';
            app.textVideoDir = uilabel(app.mainGUI);
            app.textVideoDir.Text = 'Video Directory';
            app.settings = uibutton(app.GUI, 'push');
            app.settings.ButtonPushedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Icon = 'outline_settings_black_48dp.png';
            app.settings.Text = 'Settings';
            app.mainFunction = uipanel(app.GUI);
            app.mainFunction.Title = 'Main function';
            app.analyse = uibutton(app.mainFunction, 'push');
            app.analyse.ButtonPushedFcn = createCallbackFcn(app, @analyse_Callback, true);
            app.analyse.Enable = 'off';
            app.analyse.Text = 'Analyse (3)';
            app.prepare = uibutton(app.mainFunction, 'push');
            app.prepare.ButtonPushedFcn = createCallbackFcn(app, @prepare_Callback, true);
            app.prepare.Text = 'Prepare (1)';
            app.filter = uibutton(app.mainFunction, 'push');
            app.filter.ButtonPushedFcn = createCallbackFcn(app, @filter_Callback, true);
            app.filter.Enable = 'off';
            app.filter.Text = 'Filter (2)';
            app.sideFunction = uipanel(app.GUI);
            app.sideFunction.Title = 'Side functions';
            app.BrainMap = uibutton(app.sideFunction, 'push');
            app.BrainMap.ButtonPushedFcn = createCallbackFcn(app, @BrainMapButtonPushed, true);
            app.BrainMap.Enable = 'off';
            app.BrainMap.Text = 'BrainMap';
            app.MomentaryFreq = uibutton(app.sideFunction, 'push');
            app.MomentaryFreq.ButtonPushedFcn = createCallbackFcn(app, @MomentaryFreqButtonPushed, true);
            app.MomentaryFreq.Enable = 'off';
            app.MomentaryFreq.Text = 'Momentary Freq.';
            app.SettingsBorder = uipanel(app.GUI);
            app.SettingsBorder.Title = ' ';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdAnalyser_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @AdAnalyser_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end