classdef ConfigDialog_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                       matlab.ui.Figure
        OutputSettingsPanel       matlab.ui.container.Panel
        boxbehavCharacteristics   matlab.ui.control.CheckBox
        boxRawEEG                 matlab.ui.control.CheckBox
        boxEEGFrequency           matlab.ui.control.CheckBox
        boxRawEDA                 matlab.ui.control.CheckBox
        boxRawHRV                 matlab.ui.control.CheckBox
        boxDetrendedEDA           matlab.ui.control.CheckBox
        boxSubStimuIntEDA         matlab.ui.control.CheckBox
        boxStats                  matlab.ui.control.CheckBox
        box2DTopo                 matlab.ui.control.CheckBox
        boxBrainActivity          matlab.ui.control.CheckBox
        boxEDARecurrence          matlab.ui.control.CheckBox
        boxHRVRecurrence          matlab.ui.control.CheckBox
        ReccurenceSettingsPanel   matlab.ui.container.Panel
        textThreshold             matlab.ui.control.Label
        displayResccurence        matlab.ui.control.EditField
        ok                        matlab.ui.control.Button
        Cancel                    matlab.ui.control.Button
        allOutputs                matlab.ui.control.Button
        noOutputs                 matlab.ui.control.Button
        ElectrodeDialogPanel      matlab.ui.container.Panel
        ElectrodeDialog           matlab.ui.control.Button
        EEGQualitySettingsPanel   matlab.ui.container.Panel
        textQualityIndex          matlab.ui.control.Label
        textUpperThreshold        matlab.ui.control.Label
        textLowerThreshold        matlab.ui.control.Label
        displayLowerThreshold     matlab.ui.control.EditField
        textLowerUnit             matlab.ui.control.Label
        displayUpperThreshold     matlab.ui.control.EditField
        textUpperUnit             matlab.ui.control.Label
        displayQualityIndex       matlab.ui.control.EditField
        textQualityUnit           matlab.ui.control.Label
        displayCutOffValue        matlab.ui.control.EditField
        textCutOffValue           matlab.ui.control.Label
        textCutOffUnit            matlab.ui.control.Label
        VideoOutputSettingsPanel  matlab.ui.container.Panel
        textFrames                matlab.ui.control.Label
        displayFrames             matlab.ui.control.EditField
        boxVideoOutput            matlab.ui.control.CheckBox
        RangeSettingsPanel        matlab.ui.container.Panel
        text2DTopo                matlab.ui.control.Label
        display2DTopo             matlab.ui.control.EditField
        textBrainActivity         matlab.ui.control.Label
        displayBrainActivity      matlab.ui.control.EditField
        NumberSubjectsPanel       matlab.ui.container.Panel
        displaySubjects           matlab.ui.control.EditField
        textSubjects              matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        header                    matlab.ui.control.Label
        ExportDataPanel           matlab.ui.container.Panel
        ExportSettings            matlab.ui.control.Button
    end

    %This is the Settings GUI for the AdAnalyser.

    % The user can set the different settings for the analysis.
    % The output of the program can be switched on and off, the quality settings 
    % for the EEG analysis can be set and the settings for different plots can 
    % be defined. For example, the user can set different intervals at which 
    % the 2D Topology Maps plot is generated. 
    % The user can also open the electrode selection.
    %
    % Author: Tim Kreitzberg
    %

    %function to update GUI accordingly to their values in conf
    methods (Access = private)
        function updateUI(app)
            global conf;
            app.boxRawEEG.Value = conf.EEG_DEVICE_USED;
            app.boxRawEDA.Value = conf.EDA_DEVICE_USED;
            app.boxDetrendedEDA.Value = conf.DetrendedEDAFig;
            app.boxEEGFrequency.Value = conf.FrequencyFig;
            app.boxStats.Value = conf.Statistics;
            app.boxHRVRecurrence.Value = conf.HRVRecurrence;
            app.boxEDARecurrence.Value = conf.EDARecurrence;
            app.boxbehavCharacteristics.Value = conf.BehaveFig;
            app.boxRawHRV.Value = conf.HRV_DEVICE_USED;
            app.boxSubStimuIntEDA.Value = conf.SubStimuIntEDAFig;
            app.box2DTopo.Value = conf.topoplot;
            app.boxBrainActivity.Value = conf.brainactivity;
            app.displayUpperThreshold.Value = num2str(conf.UpperThreshold);
            app.displayLowerThreshold.Value = num2str(conf.LowerThreshold);
            app.displayQualityIndex.Value = num2str(conf.QualityIndex);
            app.displayResccurence.Value = num2str(conf.RecurrenceThreshold);
            app.displayCutOffValue.Value = num2str(conf.EEGCutoffValue);
            app.display2DTopo.Value = num2str(conf.TopoRange);
            app.displaySubjects.Value = num2str(conf.numSubjects);
            app.displayBrainActivity.Value = num2str(conf.BrainRange);
            app.displayFrames.Value = num2str(conf.UserFrameRate);
            app.boxVideoOutput.Value = conf.videoOutput;
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ConfigDialog_OpeningFcn(app)
            app.GUI.Visible = 'off';
            movegui(app.GUI,"center");
            app.GUI.Visible = 'on';
            %executes the updateUI to change everything accordingly to conf
            updateUI(app);
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
        %close window
            delete(app);
        end

        % Button pushed function: allOutputs
        function allOutputs_Callback(app, event)
            %turn on all output settings
            app.boxRawEEG.Value = 1;
            app.boxRawEDA.Value = 1;
            app.boxDetrendedEDA.Value = 1;
            app.boxEEGFrequency.Value = 1;
            app.boxStats.Value = 1;
            app.boxHRVRecurrence.Value = 1;
            app.boxEDARecurrence.Value = 1;
            app.boxbehavCharacteristics.Value = 1;
            app.boxRawHRV.Value = 1;
            app.boxSubStimuIntEDA.Value = 1;
            app.box2DTopo.Value = 1;
            app.boxBrainActivity.Value = 1;
            app.boxVideoOutput.Value = 1;
        end

        % Button pushed function: noOutputs
        function noOutputs_Callback(app, event)
            %turn off all output settings
            app.boxRawEEG.Value = 0;
            app.boxRawEDA.Value = 0;
            app.boxDetrendedEDA.Value = 0;
            app.boxEEGFrequency.Value = 0;
            app.boxStats.Value = 0;
            app.boxHRVRecurrence.Value = 0;
            app.boxEDARecurrence.Value = 0;
            app.boxbehavCharacteristics.Value = 0;
            app.boxRawHRV.Value = 0;
            app.boxSubStimuIntEDA.Value = 0;
            app.box2DTopo.Value = 0;
            app.boxBrainActivity.Value = 0;
            app.boxVideoOutput.Value = 0;
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            global conf
            % initialize Output flags - update conf
            conf.EEG_DEVICE_USED = app.boxRawEEG.Value;
            conf.FrequencyFig = app.boxEEGFrequency.Value;
            conf.EDA_DEVICE_USED = app.boxRawEDA.Value;
            conf.DetrendedEDAFig = app.boxDetrendedEDA.Value;
            conf.HRVRecurrence = app.boxHRVRecurrence.Value;
            conf.EDARecurrence = app.boxEDARecurrence.Value;
            conf.HRV_DEVICE_USED = app.boxRawHRV.Value;
            conf.SubStimuIntEDAFig = app.boxSubStimuIntEDA.Value;
            conf.BehaveFig = app.boxbehavCharacteristics.Value;
            conf.Statistics = app.boxStats.Value;
            conf.EEGCutoffValue = app.displayCutOffValue.Value;
            conf.numSubjects = app.displaySubjects.Value;
            conf.topoplot = app.box2DTopo.Value;
            conf.TopoRange = app.display2DTopo.Value;
            conf.brainactivity = app.boxBrainActivity.Value;
            conf.BrainRange = app.displayBrainActivity.Value;
            conf.UserFrameRate = app.displayFrames.Value;
            conf.videoOutput = app.boxVideoOutput.Value;


            % Quality Settings
            valid = 1;
            str = app.displayLowerThreshold.Value;
            %check string, if inputs are valid
            if isempty(str)
                app.displayLowerThreshold = -100;
                warndlg('Input must be numerical','Invalid lower threshold');
                valid = 0;
            else
                %save string in conf
                conf.LowerThreshold = str2double(str);
            end
            
            str = app.displayUpperThreshold.Value;
            if isempty(str)
                app.displayUpperThreshold = 100;
                warndlg('Input must be numerical','Invalid upper threshold');
                valid = 0;
            else
                conf.UpperThreshold = str2double(str);
            end
            
            str = app.displayResccurence.Value;
            if isempty(str)
                app.displayResccurence = 0;
                warndlg('Input must be numerical','Invalid recurrence threshold');
                valid = 0;
            else
                conf.RecurrenceThreshold = str2double(str);
            end
            
            str = app.displayQualityIndex.Value;
            if isempty(str)
                app.displayQualityIndex = 10;
                warndlg('Input must be numerical','Invalid quality index');
                valid = 0;
            else
                conf.QualityIndex = str2double(str);
            end
            
            str = app.displayCutOffValue.Value;
            if isempty(str)
                app.displayCutOffValue = 10;
                warndlg('Input must be numerical','Invalid EEG CutOff Value');
                valid = 0;
            else
                conf.EEGCutoffValue = str2double(str);
            end
            
            str = app.display2DTopo.Value;
            if isempty(str)
                app.display2DTopo = 1000;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            elseif  str2double(str) < 1000
                warndlg(['Values for Topology maps wrong!' ,newline, 'Input value needs to be 1000ms or higher'],'Invalid Interval');
                valid = 0;
            else
                conf.TopoRange = str2double(str);
            end
            
            str = app.displayBrainActivity.Value;
            if isempty(str)
                app.displayBrainActivity = 2000;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            else
                conf.BrainRange = str2double(str);
            end
            
            str = app.displayFrames.Value;
            if isempty(str)
                app.displayFrames = 30;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            else
                conf.UserFrameRate = str2double(str);
            end
            
            
            str = app.displaySubjects.Value;
            if isempty(str)
                app.displaySubjects = 1;
                warndlg('Input must be numerical','Invalid Interval');
                valid = 0;
            else
                conf.numSubjects = str2double(str);
            end
            
            %if all inputs are valid, close window
            if (valid==1)
                delete(app);
            end
        end

        % Button pushed function: ElectrodeDialog
        function ElectrodeDialog_Callback(app, event)
            %Calls electrode dialog
            ElectrodesDialog_App;
        end

        % Callback function
        function exportButtonPushed(app, event)
            ExportDialog_App;
        end

        % Button pushed function: ExportSettings
        function ExportSettingsPushed(app, event)
            ExportDialog_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'Settings Dialog';
            app.OutputSettingsPanel = uipanel(app.GUI);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.boxbehavCharacteristics = uicheckbox(app.OutputSettingsPanel);
            app.boxbehavCharacteristics.Text = 'Behavioral Characteristics';
            app.boxRawEEG = uicheckbox(app.OutputSettingsPanel);
            app.boxRawEEG.Text = 'Raw EEG';
            app.boxEEGFrequency = uicheckbox(app.OutputSettingsPanel);
            app.boxEEGFrequency.Text = 'EEG Frequencies';
            app.boxRawEDA = uicheckbox(app.OutputSettingsPanel);
            app.boxRawEDA.Text = 'Raw EDA';
            app.boxRawHRV = uicheckbox(app.OutputSettingsPanel);
            app.boxRawHRV.Text = 'Raw HRV';
            app.boxDetrendedEDA = uicheckbox(app.OutputSettingsPanel);
            app.boxDetrendedEDA.Text = 'Detrended EDA';
            app.boxSubStimuIntEDA = uicheckbox(app.OutputSettingsPanel);
            app.boxSubStimuIntEDA.Text = 'Subvideo EDA';
            app.boxStats = uicheckbox(app.OutputSettingsPanel);
            app.boxStats.Text = 'Statistics';
            app.box2DTopo = uicheckbox(app.OutputSettingsPanel);
            app.box2DTopo.Text = '2D Topology Map';
            app.boxBrainActivity = uicheckbox(app.OutputSettingsPanel);
            app.boxBrainActivity.Text = 'Brain activity Plot';
            app.boxEDARecurrence = uicheckbox(app.OutputSettingsPanel);
            app.boxEDARecurrence.Text = 'EDA Recurrence';
            app.boxHRVRecurrence = uicheckbox(app.OutputSettingsPanel);
            app.boxHRVRecurrence.Text = 'HRV Recurrence';
            app.ReccurenceSettingsPanel = uipanel(app.GUI);
            app.ReccurenceSettingsPanel.Title = 'Reccurence Plot Settings';
            app.textThreshold = uilabel(app.ReccurenceSettingsPanel);
            app.textThreshold.Text = 'Threshold';
            app.displayResccurence = uieditfield(app.ReccurenceSettingsPanel, 'text');
            app.displayResccurence.Value = '0';
            app.ok = uibutton(app.GUI, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Icon = 'outline_done_black_48dp.png';
            app.ok.Text = 'Ok';
            app.Cancel = uibutton(app.GUI, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Icon = 'outline_close_black_48dp.png';
            app.Cancel.Text = 'Cancel';
            app.allOutputs = uibutton(app.GUI, 'push');
            app.allOutputs.ButtonPushedFcn = createCallbackFcn(app, @allOutputs_Callback, true);
            app.allOutputs.Text = 'All';
            app.noOutputs = uibutton(app.GUI, 'push');
            app.noOutputs.ButtonPushedFcn = createCallbackFcn(app, @noOutputs_Callback, true);
            app.noOutputs.Text = 'None';
            app.ElectrodeDialogPanel = uipanel(app.GUI);
            app.ElectrodeDialogPanel.Title = 'Select electrodes';
            app.ElectrodeDialog = uibutton(app.ElectrodeDialogPanel, 'push');
            app.ElectrodeDialog.ButtonPushedFcn = createCallbackFcn(app, @ElectrodeDialog_Callback, true);
            app.ElectrodeDialog.Icon = 'baseline_psychology_black_48dp.png';
            app.ElectrodeDialog.Text = 'Select Electrodes';
            app.EEGQualitySettingsPanel = uipanel(app.GUI);
            app.EEGQualitySettingsPanel.Title = 'EEG Quality Settings';
            app.textQualityIndex = uilabel(app.EEGQualitySettingsPanel);
            app.textQualityIndex.Text = 'Quality Index';
            app.textUpperThreshold = uilabel(app.EEGQualitySettingsPanel);
            app.textUpperThreshold.Text = 'Upper Threshold';
            app.textLowerThreshold = uilabel(app.EEGQualitySettingsPanel);
            app.textLowerThreshold.Text = 'Lower Threshold';
            app.displayLowerThreshold = uieditfield(app.EEGQualitySettingsPanel, 'text');
            app.displayLowerThreshold.Value = '-100';
            app.textLowerUnit = uilabel(app.EEGQualitySettingsPanel);
            app.textLowerUnit.Text = 'µV';
            app.displayUpperThreshold = uieditfield(app.EEGQualitySettingsPanel, 'text');
            app.displayUpperThreshold.Value = '100';
            app.textUpperUnit = uilabel(app.EEGQualitySettingsPanel);
            app.textUpperUnit.Text = 'µV';
            app.displayQualityIndex = uieditfield(app.EEGQualitySettingsPanel, 'text');
            app.displayQualityIndex.Value = '10';
            app.textQualityUnit = uilabel(app.EEGQualitySettingsPanel);
            app.textQualityUnit.Text = '%';
            app.displayCutOffValue = uieditfield(app.EEGQualitySettingsPanel, 'text');
            app.displayCutOffValue.Value = '10';
            app.textCutOffValue = uilabel(app.EEGQualitySettingsPanel);
            app.textCutOffValue.Text = 'EEG Cut-Off Value';
            app.textCutOffUnit = uilabel(app.EEGQualitySettingsPanel);
            app.textCutOffUnit.Text = 's';
            app.VideoOutputSettingsPanel = uipanel(app.GUI);
            app.VideoOutputSettingsPanel.Title = 'Video Output Settings';
            app.textFrames = uilabel(app.VideoOutputSettingsPanel);
            app.textFrames.Text = 'Set frames per second for video output';
            app.displayFrames = uieditfield(app.VideoOutputSettingsPanel, 'text');
            app.displayFrames.Value = '30';
            app.boxVideoOutput = uicheckbox(app.VideoOutputSettingsPanel);
            app.boxVideoOutput.Text = 'Video ouput';
            app.RangeSettingsPanel = uipanel(app.GUI);
            app.RangeSettingsPanel.Title = 'Range for prints';
            app.text2DTopo = uilabel(app.RangeSettingsPanel);
            app.text2DTopo.Text = '2D Topology maps [ms]';
            app.display2DTopo = uieditfield(app.RangeSettingsPanel, 'text');
            app.display2DTopo.Value = '500';
            app.textBrainActivity = uilabel(app.RangeSettingsPanel);
            app.textBrainActivity.Text = 'Brain activity Plot [ms]';
            app.displayBrainActivity = uieditfield(app.RangeSettingsPanel, 'text');
            app.displayBrainActivity.Value = '2000';
            app.NumberSubjectsPanel = uipanel(app.GUI);
            app.NumberSubjectsPanel.Title = 'Number of subjects';
            app.displaySubjects = uieditfield(app.NumberSubjectsPanel, 'text');
            app.displaySubjects.Value = '1';
            app.textSubjects = uilabel(app.NumberSubjectsPanel);
            app.textSubjects.Text = 'Subjects';
            app.Panel = uipanel(app.GUI);
            app.Panel.Title = ' ';
            app.header = uilabel(app.GUI);
            app.header.Text = 'Settings for analyse';
            app.ExportDataPanel = uipanel(app.GUI);
            app.ExportDataPanel.Title = 'Export Data';
            app.ExportSettings = uibutton(app.ExportDataPanel, 'push');
            app.ExportSettings.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsPushed, true);
            app.ExportSettings.Icon = 'outline_file_upload_black_48dp.png';
            app.ExportSettings.Text = 'Export Settings';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigDialog_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @ConfigDialog_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end