classdef ElectrodesDialog_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI              matlab.ui.Figure
        backgroundHead   matlab.ui.control.Label
        cancel           matlab.ui.control.Button
        tableElectrodes  matlab.ui.control.Table
        allButton        matlab.ui.control.StateButton
        Head             matlab.ui.control.Image
        fp1              matlab.ui.control.StateButton
        fp2              matlab.ui.control.StateButton
        fpz              matlab.ui.control.StateButton
        fz               matlab.ui.control.StateButton
        f3               matlab.ui.control.StateButton
        f4               matlab.ui.control.StateButton
        f7               matlab.ui.control.StateButton
        f8               matlab.ui.control.StateButton
        t7               matlab.ui.control.StateButton
        t8               matlab.ui.control.StateButton
        cz               matlab.ui.control.StateButton
        c4               matlab.ui.control.StateButton
        c3               matlab.ui.control.StateButton
        p3               matlab.ui.control.StateButton
        p4               matlab.ui.control.StateButton
        pz               matlab.ui.control.StateButton
        p7               matlab.ui.control.StateButton
        p6               matlab.ui.control.StateButton
        o1               matlab.ui.control.StateButton
        o2               matlab.ui.control.StateButton
        oz               matlab.ui.control.StateButton
        af3              matlab.ui.control.StateButton
        af4              matlab.ui.control.StateButton
        poz              matlab.ui.control.StateButton
        t10              matlab.ui.control.StateButton
        t9               matlab.ui.control.StateButton
        nz               matlab.ui.control.StateButton
        afz              matlab.ui.control.StateButton
        af7              matlab.ui.control.StateButton
        af8              matlab.ui.control.StateButton
        f5               matlab.ui.control.StateButton
        f6               matlab.ui.control.StateButton
        f1               matlab.ui.control.StateButton
        f2               matlab.ui.control.StateButton
        f9               matlab.ui.control.StateButton
        f10              matlab.ui.control.StateButton
        fc3              matlab.ui.control.StateButton
        ft7              matlab.ui.control.StateButton
        fc5              matlab.ui.control.StateButton
        fc1              matlab.ui.control.StateButton
        ft9              matlab.ui.control.StateButton
        fcz              matlab.ui.control.StateButton
        fc4              matlab.ui.control.StateButton
        ft8              matlab.ui.control.StateButton
        fc6              matlab.ui.control.StateButton
        fc2              matlab.ui.control.StateButton
        ft10             matlab.ui.control.StateButton
        a1               matlab.ui.control.StateButton
        c5               matlab.ui.control.StateButton
        c1               matlab.ui.control.StateButton
        a2               matlab.ui.control.StateButton
        c6               matlab.ui.control.StateButton
        c2               matlab.ui.control.StateButton
        cp3              matlab.ui.control.StateButton
        fp7              matlab.ui.control.StateButton
        cp5              matlab.ui.control.StateButton
        cp1              matlab.ui.control.StateButton
        tp9              matlab.ui.control.StateButton
        cpz              matlab.ui.control.StateButton
        cp4              matlab.ui.control.StateButton
        tp8              matlab.ui.control.StateButton
        cp6              matlab.ui.control.StateButton
        cp2              matlab.ui.control.StateButton
        tp10             matlab.ui.control.StateButton
        p5               matlab.ui.control.StateButton
        p1               matlab.ui.control.StateButton
        p9               matlab.ui.control.StateButton
        p8               matlab.ui.control.StateButton
        p2               matlab.ui.control.StateButton
        p10              matlab.ui.control.StateButton
        po3              matlab.ui.control.StateButton
        po4              matlab.ui.control.StateButton
        po7              matlab.ui.control.StateButton
        po8              matlab.ui.control.StateButton
        iz               matlab.ui.control.StateButton
        ok               matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        header           matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ElectrodesDialog_OpeningFcn(app)
            % This is the Electrode Position GUI for the AdAnalyser.

            % The user can make adjustments to the different electrode 
            % positions. All possible electrode positions of the 10-20 system 
            % are displayed and the positions of the selected EEG device are 
            % highlighted. The positions can be easily activated or deactivated. 
            % 
            % Author: Tim Kreitzberg
            % 
            
            %% Upadte Togglebutton on GUI
            app.GUI.Visible = 'off';
            movegui(app.GUI,"center");
            app.GUI.Visible = 'on';
            global eegdevice
            electrodePositions = eegdevice.electrodePositions;
            numberOfElectrodes = length(electrodePositions);
            
            % Type searched for -> "buttons" and save it in properties
            typeNeeded = 'matlab.ui.control.StateButton';    
            props = properties(app);
            
            % cycle through all properties of the GUI
            for i = 1:length(props)
                currentprop = props{i};
                currentprop_value = app.(currentprop);
                type = class(currentprop_value);
                % Compare types to search for togglebuttons
                if strcmp(type,typeNeeded)
                  % set BackgroundColor to gray
                  app.(currentprop).BackgroundColor = [0.9,0.9,0.9];
                  for j = 1:numberOfElectrodes
                    if strcmp(app.(currentprop).Text,electrodePositions{j})
                        % Update togglebutton
                        app.(currentprop).Enable = 'on';
                        % tooltip saved the original color of the button
                        app.(currentprop).BackgroundColor = app.(currentprop).Tooltip;
                        % set togglebutton in "pressed" if electorde state already == 1
                        if eegdevice.electrodeState{j} == 1
                            app.(currentprop).Value = true;
                        else
                            app.(currentprop).Value = false;
                        end 
                    end
                  end
                end
            end 
            
            % Create table for positions
            for j = 1:numberOfElectrodes
                app.tableElectrodes.Data{j,1} =  electrodePositions{j};
                app.tableElectrodes.Data{j,2} =  logical(eegdevice.electrodeState{j});
            end
        end

        % Value changed function: allButton
        function allButton_Callback(app, event)
            % Change all positions on/off
            % Upadte Togglebutton on GUI
            global eegdevice
            electrodePositions = eegdevice.electrodePositions;      
            
            % settings for loop
            props = properties(app);
            % get the index of all properties with electrodes
            idxOfElc = ~ismember(lower(props(:,1)),lower(electrodePositions));
            props(idxOfElc,:) = [];
            
            %cycle through all electrode positions and update the table
            %accordingly
            for i = 1:length(props)
                currentprop = props{i};
                if app.allButton.Value == false
                    app.(currentprop).Value = false;
                    index = find(ismember(lower(props(:,1)),currentprop)==1);
                    app.tableElectrodes.Data{index,2} =  false;
                else
                    app.(currentprop).Value = true;
                    index = find(ismember(lower(props(:,1)),currentprop)==1);
                    app.tableElectrodes.Data{index,2} =  true;
                end
            end
        end

        % Value changed function: a1
        function a1_Callback(app, event)
            % Code for each button
            % checks if the electrode is present in the EEG device by
            % checking the data variable 
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'A1')
                data{i,2} = app.a1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: a2
        function a2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'A2')
                data{i,2} = app.a2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: af3
        function af3_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'AF3')
                data{i,2} = app.af3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: af4
        function af4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'AF4')
                data{i,2} = app.af4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: af7
        function af7_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'AF7')
                data{i,2} = app.af7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: af8
        function af8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'AF8')
                data{i,2} = app.af8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: afz
        function afz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'AFz')
                data{i,2} = app.afz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: c1
        function c1_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'C1')
                data{i,2} = app.c1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: c2
        function c2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'C2')
                data{i,2} = app.c2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: c3
        function c3_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'C3')
                data{i,2} = app.c3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: c4
        function c4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'C4')
                data{i,2} = app.c4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: c5
        function c5_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'C5')
                data{i,2} = app.c5.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: c6
        function c6_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'C6')
                data{i,2} = app.c6.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            delete(app);
        end

        % Value changed function: cp1
        function cp1_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CP1')
                data{i,2} = app.cp1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cp2
        function cp2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CP2')
                data{i,2} = app.cp2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cp3
        function cp3_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CP3')
                data{i,2} = app.cp3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cp4
        function cp4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CP4')
                data{i,2} = app.cp4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cp5
        function cp5_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CP5')
                data{i,2} = app.cp5.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cp6
        function cp6_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CP6')
                data{i,2} = app.cp6.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cpz
        function cpz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'CPz')
                data{i,2} = app.cpz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: cz
        function cz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'Cz')
                data{i,2} = app.cz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f10
        function f10_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F10')
                data{i,2} = app.f10.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f1
        function f1_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F1')
                data{i,2} = app.f1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f2
        function f2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F2')
                data{i,2} = app.f2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f3
        function f3_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F3')
                data{i,2} = app.f3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f4
        function f4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F4')
                data{i,2} = app.f4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f5
        function f5_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F5')
                data{i,2} = app.f5.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f6
        function f6_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F6')
                data{i,2} = app.f6.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f7
        function f7_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F7')
                data{i,2} = app.f7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f8
        function f8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F8')
                data{i,2} = app.f8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: f9
        function f9_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'F9')
                data{i,2} = app.f9.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fc2
        function fc2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FC2')
                data{i,2} = app.fc2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fc4
        function fc4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FC4')
                data{i,2} = app.fc4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fc6
        function fc6_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FC6')
                data{i,2} = app.fc6.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fcz
        function fcz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FCz')
                data{i,2} = app.fcz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fp1
        function fp1_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FP1')
                data{i,2} = app.fp1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fp2
        function fp2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data); dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FP2')
                data{i,2} = app.fp2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Callback function
        function fp7_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FP7')
                data{i,2} = app.fp7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fpz
        function fpz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FPz')
                data{i,2} = app.fpz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: ft10
        function ft10_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FT10')
                data{i,2} = app.ft10.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: ft8
        function ft8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FT8')
                data{i,2} = app.ft8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: ft9
        function ft9_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FT9')
                data{i,2} = app.ft9.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fz
        function fz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'Fz')
                data{i,2} = app.fz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: iz
        function iz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'Iz')
                data{i,2} = app.iz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: nz
        function nz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'Nz')
                data{i,2} = app.nz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: o1
        function o1_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'O1')
                data{i,2} = app.o1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: o2
        function o2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'O2')
                data{i,2} = app.o2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Callback function
        function ok_Callback(app, event)
            global eegdevice
            
            data = app.tableElectrodes.Data;
            VectorSize = size(data);
            LengthData = VectorSize(1);
            
            for i = 1:LengthData
                eegdevice.electrodeState{i} = data{i,2};
            end
            
            sumState = sum([eegdevice.electrodeState{:}]);
            if sumState == 0
                warndlg(['Warning: No electrode is chosen!', newline,'Please chose at least one electrode'])
            else
            delete(app);
            end
        end

        % Value changed function: oz
        function oz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'Oz')
                data{i,2} = app.oz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p10
        function p10_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P10')
                data{i,2} = app.p10.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p1
        function p1_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P1')
                data{i,2} = app.p1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p2
        function p2_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P2')
                data{i,2} = app.p2.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p3
        function p3_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P3')
                data{i,2} = app.p3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p4
        function p4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P4')
                data{i,2} = app.p4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p5
        function p5_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P5')
                data{i,2} = app.p5.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p6
        function p6_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P6')
                data{i,2} = app.p6.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p7
        function p7_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P7')
                data{i,2} = app.p7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p8
        function p8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P8')
                data{i,2} = app.p8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: p9
        function p9_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'P9')
                data{i,2} = app.p9.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: po3
        function po3_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'PO3')
                data{i,2} = app.po3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: po4
        function po4_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'PO4')
                data{i,2} = app.po4.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: po7
        function po7_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'PO7')
                data{i,2} = app.po7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: po8
        function po8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'PO8')
                data{i,2} = app.po8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: poz
        function poz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'POz')
                data{i,2} = app.poz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: pz
        function pz_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'Pz')
                data{i,2} = app.pz.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: t10
        function t10_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'T10')
                data{i,2} = app.t10.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: t7
        function t7_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'T7')
                data{i,2} = app.t7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: t8
        function t8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'T8')
                data{i,2} = app.t8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: t9
        function t9_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'T9')
                data{i,2} = app.t9.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: tp10
        function tp10_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'TP10')
                data{i,2} = app.tp10.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: tp8
        function tp8_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'TP8')
                data{i,2} = app.tp8.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: tp9
        function tp9_Callback(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'TP9')
                data{i,2} = app.tp9.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Button pushed function: ok
        function okButtonPushed(app, event)
            global eegdevice
            
            data = app.tableElectrodes.Data;
            VectorSize = size(data);
            LengthData = VectorSize(1);
            
            for i = 1:LengthData
                eegdevice.electrodeState{i} = data{i,2};
            end
            
            sumState = sum([eegdevice.electrodeState{:}]);
            if sumState == 0
                warndlg(['Warning: No electrode is chosen!', newline,'Please chose at least one electrode'])
            elseif sumState > 9
                warndlg(['Warning: To many electrodes where chosen!', newline,'Please chose at max nine electrode'])
            else
            delete(app);
            end
        end

        % Value changed function: fp7
        function tp7ValueChanged(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'TP7')
                data{i,2} = app.tp9.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: ft7
        function ft7ValueChanged(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FT7')
                data{i,2} = app.ft7.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fc5
        function fc5ValueChanged(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FC5')
                data{i,2} = app.fc5.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fc3
        function fc3ValueChanged(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FC3')
                data{i,2} = app.fc3.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end

        % Value changed function: fc1
        function fc1ValueChanged(app, event)
            data = get(app.tableElectrodes,'data');
            dataVector = size(data);
            dataL = dataVector(1);
            for i = 1:dataL
                if strcmp(data{i,1},'FC1')
                data{i,2} = app.fc1.Value;
                end
            end
            set(app.tableElectrodes, 'Data', data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'ElectrodesDialog';
            app.backgroundHead = uilabel(app.GUI);
            app.cancel = uibutton(app.GUI, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Icon = 'outline_close_black_48dp.png';
            app.cancel.Text = 'Cancel';
            app.tableElectrodes = uitable(app.GUI);
            app.tableElectrodes.ColumnName = {'Electordes'; 'Status'};
            app.tableElectrodes.ColumnEditable = [false false];
            app.allButton = uibutton(app.GUI, 'state');
            app.allButton.ValueChangedFcn = createCallbackFcn(app, @allButton_Callback, true);
            app.allButton.Text = 'All on/off';
            app.allButton.Value = true;
            app.Head = uiimage(app.GUI);
            app.Head.ImageSource = 'EEG_Background.jpg';
            app.fp1 = uibutton(app.GUI, 'state');
            app.fp1.ValueChangedFcn = createCallbackFcn(app, @fp1_Callback, true);
            app.fp1.Enable = 'off';
            app.fp1.Tooltip = 'Red';
            app.fp1.Text = 'FP1';
            app.fp2 = uibutton(app.GUI, 'state');
            app.fp2.ValueChangedFcn = createCallbackFcn(app, @fp2_Callback, true);
            app.fp2.Enable = 'off';
            app.fp2.Tooltip = '[0.04,0.52,0.78]';
            app.fp2.Text = 'FP2';
            app.fpz = uibutton(app.GUI, 'state');
            app.fpz.ValueChangedFcn = createCallbackFcn(app, @fpz_Callback, true);
            app.fpz.Enable = 'off';
            app.fpz.Tooltip = '[0,0.5,0]';
            app.fpz.Text = 'FPz';
            app.fz = uibutton(app.GUI, 'state');
            app.fz.ValueChangedFcn = createCallbackFcn(app, @fz_Callback, true);
            app.fz.Enable = 'off';
            app.fz.Tooltip = '[0.31,0.31,0.31]';
            app.fz.Text = 'Fz';
            app.f3 = uibutton(app.GUI, 'state');
            app.f3.ValueChangedFcn = createCallbackFcn(app, @f3_Callback, true);
            app.f3.Enable = 'off';
            app.f3.Tooltip = 'Red';
            app.f3.Text = 'F3';
            app.f4 = uibutton(app.GUI, 'state');
            app.f4.ValueChangedFcn = createCallbackFcn(app, @f4_Callback, true);
            app.f4.Enable = 'off';
            app.f4.Tooltip = '[0.04,0.52,0.78]';
            app.f4.Text = 'F4';
            app.f7 = uibutton(app.GUI, 'state');
            app.f7.ValueChangedFcn = createCallbackFcn(app, @f7_Callback, true);
            app.f7.Enable = 'off';
            app.f7.Tooltip = 'Red';
            app.f7.Text = 'F7';
            app.f8 = uibutton(app.GUI, 'state');
            app.f8.ValueChangedFcn = createCallbackFcn(app, @f8_Callback, true);
            app.f8.Enable = 'off';
            app.f8.Tooltip = '[0.04,0.52,0.78]';
            app.f8.Text = 'F8';
            app.t7 = uibutton(app.GUI, 'state');
            app.t7.ValueChangedFcn = createCallbackFcn(app, @t7_Callback, true);
            app.t7.Enable = 'off';
            app.t7.Tooltip = 'Red';
            app.t7.Text = 'T7';
            app.t8 = uibutton(app.GUI, 'state');
            app.t8.ValueChangedFcn = createCallbackFcn(app, @t8_Callback, true);
            app.t8.Enable = 'off';
            app.t8.Tooltip = '[0.04,0.52,0.78]';
            app.t8.Text = 'T8';
            app.cz = uibutton(app.GUI, 'state');
            app.cz.ValueChangedFcn = createCallbackFcn(app, @cz_Callback, true);
            app.cz.Enable = 'off';
            app.cz.Tooltip = '[0.31,0.31,0.31]';
            app.cz.Text = 'Cz';
            app.c4 = uibutton(app.GUI, 'state');
            app.c4.ValueChangedFcn = createCallbackFcn(app, @c4_Callback, true);
            app.c4.Enable = 'off';
            app.c4.Tooltip = '[0.04,0.52,0.78]';
            app.c4.Text = 'C4';
            app.c3 = uibutton(app.GUI, 'state');
            app.c3.ValueChangedFcn = createCallbackFcn(app, @c3_Callback, true);
            app.c3.Enable = 'off';
            app.c3.Tooltip = 'Red';
            app.c3.Text = 'C3';
            app.p3 = uibutton(app.GUI, 'state');
            app.p3.ValueChangedFcn = createCallbackFcn(app, @p3_Callback, true);
            app.p3.Enable = 'off';
            app.p3.Tooltip = 'Red';
            app.p3.Text = 'P3';
            app.p4 = uibutton(app.GUI, 'state');
            app.p4.ValueChangedFcn = createCallbackFcn(app, @p4_Callback, true);
            app.p4.Enable = 'off';
            app.p4.Tooltip = '[0.04,0.52,0.78]';
            app.p4.Text = 'P4';
            app.pz = uibutton(app.GUI, 'state');
            app.pz.ValueChangedFcn = createCallbackFcn(app, @pz_Callback, true);
            app.pz.Enable = 'off';
            app.pz.Tooltip = '[0.31,0.31,0.31]';
            app.pz.Text = 'Pz';
            app.p7 = uibutton(app.GUI, 'state');
            app.p7.ValueChangedFcn = createCallbackFcn(app, @p7_Callback, true);
            app.p7.Enable = 'off';
            app.p7.Tooltip = 'Red';
            app.p7.Text = 'P7';
            app.p6 = uibutton(app.GUI, 'state');
            app.p6.ValueChangedFcn = createCallbackFcn(app, @p6_Callback, true);
            app.p6.Enable = 'off';
            app.p6.Tooltip = '[0.04,0.52,0.78]';
            app.p6.Text = 'P6';
            app.o1 = uibutton(app.GUI, 'state');
            app.o1.ValueChangedFcn = createCallbackFcn(app, @o1_Callback, true);
            app.o1.Enable = 'off';
            app.o1.Tooltip = 'Red';
            app.o1.Text = 'O1';
            app.o2 = uibutton(app.GUI, 'state');
            app.o2.ValueChangedFcn = createCallbackFcn(app, @o2_Callback, true);
            app.o2.Enable = 'off';
            app.o2.Tooltip = '[0.04,0.52,0.78]';
            app.o2.Text = 'O2';
            app.oz = uibutton(app.GUI, 'state');
            app.oz.ValueChangedFcn = createCallbackFcn(app, @oz_Callback, true);
            app.oz.Enable = 'off';
            app.oz.Tooltip = '[0,0.5,0]';
            app.oz.Text = 'Oz';
            app.af3 = uibutton(app.GUI, 'state');
            app.af3.ValueChangedFcn = createCallbackFcn(app, @af3_Callback, true);
            app.af3.Enable = 'off';
            app.af3.Tooltip = 'Red';
            app.af3.Text = 'AF3';
            app.af4 = uibutton(app.GUI, 'state');
            app.af4.ValueChangedFcn = createCallbackFcn(app, @af4_Callback, true);
            app.af4.Enable = 'off';
            app.af4.Tooltip = '[0.04,0.52,0.78]';
            app.af4.Text = 'AF4';
            app.poz = uibutton(app.GUI, 'state');
            app.poz.ValueChangedFcn = createCallbackFcn(app, @poz_Callback, true);
            app.poz.Enable = 'off';
            app.poz.Tooltip = '[0.31,0.31,0.31]';
            app.poz.Text = 'POz';
            app.t10 = uibutton(app.GUI, 'state');
            app.t10.ValueChangedFcn = createCallbackFcn(app, @t10_Callback, true);
            app.t10.Enable = 'off';
            app.t10.Tooltip = '[0.04,0.52,0.78]';
            app.t10.Text = 'T10';
            app.t9 = uibutton(app.GUI, 'state');
            app.t9.ValueChangedFcn = createCallbackFcn(app, @t9_Callback, true);
            app.t9.Enable = 'off';
            app.t9.Tooltip = 'Red';
            app.t9.Text = 'T9';
            app.nz = uibutton(app.GUI, 'state');
            app.nz.ValueChangedFcn = createCallbackFcn(app, @nz_Callback, true);
            app.nz.Enable = 'off';
            app.nz.Tooltip = '[0,0.5,0]';
            app.nz.Text = 'Nz';
            app.afz = uibutton(app.GUI, 'state');
            app.afz.ValueChangedFcn = createCallbackFcn(app, @afz_Callback, true);
            app.afz.Enable = 'off';
            app.afz.Tooltip = '[0.31,0.31,0.31]';
            app.afz.Text = 'AFz';
            app.af7 = uibutton(app.GUI, 'state');
            app.af7.ValueChangedFcn = createCallbackFcn(app, @af7_Callback, true);
            app.af7.Enable = 'off';
            app.af7.Tooltip = 'Red';
            app.af7.Text = 'AF7';
            app.af8 = uibutton(app.GUI, 'state');
            app.af8.ValueChangedFcn = createCallbackFcn(app, @af8_Callback, true);
            app.af8.Enable = 'off';
            app.af8.Tooltip = '[0.04,0.52,0.78]';
            app.af8.Text = 'AF8';
            app.f5 = uibutton(app.GUI, 'state');
            app.f5.ValueChangedFcn = createCallbackFcn(app, @f5_Callback, true);
            app.f5.Enable = 'off';
            app.f5.Tooltip = 'Red';
            app.f5.Text = 'F5';
            app.f6 = uibutton(app.GUI, 'state');
            app.f6.ValueChangedFcn = createCallbackFcn(app, @f6_Callback, true);
            app.f6.Enable = 'off';
            app.f6.Tooltip = '[0.04,0.52,0.78]';
            app.f6.Text = 'F6';
            app.f1 = uibutton(app.GUI, 'state');
            app.f1.ValueChangedFcn = createCallbackFcn(app, @f1_Callback, true);
            app.f1.Enable = 'off';
            app.f1.Tooltip = 'Red';
            app.f1.Text = 'F1';
            app.f2 = uibutton(app.GUI, 'state');
            app.f2.ValueChangedFcn = createCallbackFcn(app, @f2_Callback, true);
            app.f2.Enable = 'off';
            app.f2.Tooltip = '[0.04,0.52,0.78]';
            app.f2.Text = 'F2';
            app.f9 = uibutton(app.GUI, 'state');
            app.f9.ValueChangedFcn = createCallbackFcn(app, @f9_Callback, true);
            app.f9.Enable = 'off';
            app.f9.Tooltip = 'Red';
            app.f9.Text = 'F9';
            app.f10 = uibutton(app.GUI, 'state');
            app.f10.ValueChangedFcn = createCallbackFcn(app, @f10_Callback, true);
            app.f10.Enable = 'off';
            app.f10.Tooltip = '[0.04,0.52,0.78]';
            app.f10.Text = 'F10';
            app.fc3 = uibutton(app.GUI, 'state');
            app.fc3.ValueChangedFcn = createCallbackFcn(app, @fc3ValueChanged, true);
            app.fc3.Enable = 'off';
            app.fc3.Tooltip = 'Red';
            app.fc3.Text = 'FC3';
            app.ft7 = uibutton(app.GUI, 'state');
            app.ft7.ValueChangedFcn = createCallbackFcn(app, @ft7ValueChanged, true);
            app.ft7.Enable = 'off';
            app.ft7.Tooltip = 'Red';
            app.ft7.Text = 'FT7';
            app.fc5 = uibutton(app.GUI, 'state');
            app.fc5.ValueChangedFcn = createCallbackFcn(app, @fc5ValueChanged, true);
            app.fc5.Enable = 'off';
            app.fc5.Tooltip = 'Red';
            app.fc5.Text = 'FC5';
            app.fc1 = uibutton(app.GUI, 'state');
            app.fc1.ValueChangedFcn = createCallbackFcn(app, @fc1ValueChanged, true);
            app.fc1.Enable = 'off';
            app.fc1.Tooltip = 'Red';
            app.fc1.Text = 'FC1';
            app.ft9 = uibutton(app.GUI, 'state');
            app.ft9.ValueChangedFcn = createCallbackFcn(app, @ft9_Callback, true);
            app.ft9.Enable = 'off';
            app.ft9.Tooltip = 'Red';
            app.ft9.Text = 'FT9';
            app.fcz = uibutton(app.GUI, 'state');
            app.fcz.ValueChangedFcn = createCallbackFcn(app, @fcz_Callback, true);
            app.fcz.Enable = 'off';
            app.fcz.Tooltip = '[0.31,0.31,0.31]';
            app.fcz.Text = 'FCz';
            app.fc4 = uibutton(app.GUI, 'state');
            app.fc4.ValueChangedFcn = createCallbackFcn(app, @fc4_Callback, true);
            app.fc4.Enable = 'off';
            app.fc4.Tooltip = '[0.04,0.52,0.78]';
            app.fc4.Text = 'FC4';
            app.ft8 = uibutton(app.GUI, 'state');
            app.ft8.ValueChangedFcn = createCallbackFcn(app, @ft8_Callback, true);
            app.ft8.Enable = 'off';
            app.ft8.Tooltip = '[0.04,0.52,0.78]';
            app.ft8.Text = 'FT8';
            app.fc6 = uibutton(app.GUI, 'state');
            app.fc6.ValueChangedFcn = createCallbackFcn(app, @fc6_Callback, true);
            app.fc6.Enable = 'off';
            app.fc6.Tooltip = '[0.04,0.52,0.78]';
            app.fc6.Text = 'FC6';
            app.fc2 = uibutton(app.GUI, 'state');
            app.fc2.ValueChangedFcn = createCallbackFcn(app, @fc2_Callback, true);
            app.fc2.Enable = 'off';
            app.fc2.Tooltip = '[0.04,0.52,0.78]';
            app.fc2.Text = 'FC2';
            app.ft10 = uibutton(app.GUI, 'state');
            app.ft10.ValueChangedFcn = createCallbackFcn(app, @ft10_Callback, true);
            app.ft10.Enable = 'off';
            app.ft10.Tooltip = '[0.04,0.52,0.78]';
            app.ft10.Text = 'FT10';
            app.a1 = uibutton(app.GUI, 'state');
            app.a1.ValueChangedFcn = createCallbackFcn(app, @a1_Callback, true);
            app.a1.Enable = 'off';
            app.a1.Tooltip = 'Red';
            app.a1.Text = 'A1';
            app.c5 = uibutton(app.GUI, 'state');
            app.c5.ValueChangedFcn = createCallbackFcn(app, @c5_Callback, true);
            app.c5.Enable = 'off';
            app.c5.Tooltip = 'Red';
            app.c5.Text = 'C5';
            app.c1 = uibutton(app.GUI, 'state');
            app.c1.ValueChangedFcn = createCallbackFcn(app, @c1_Callback, true);
            app.c1.Enable = 'off';
            app.c1.Tooltip = 'Red';
            app.c1.Text = 'C1';
            app.a2 = uibutton(app.GUI, 'state');
            app.a2.ValueChangedFcn = createCallbackFcn(app, @a2_Callback, true);
            app.a2.Enable = 'off';
            app.a2.Tooltip = '[0.04,0.52,0.78]';
            app.a2.Text = 'A2';
            app.c6 = uibutton(app.GUI, 'state');
            app.c6.ValueChangedFcn = createCallbackFcn(app, @c6_Callback, true);
            app.c6.Enable = 'off';
            app.c6.Tooltip = '[0.04,0.52,0.78]';
            app.c6.Text = 'C6';
            app.c2 = uibutton(app.GUI, 'state');
            app.c2.ValueChangedFcn = createCallbackFcn(app, @c2_Callback, true);
            app.c2.Enable = 'off';
            app.c2.Tooltip = '[0.04,0.52,0.78]';
            app.c2.Text = 'C2';
            app.cp3 = uibutton(app.GUI, 'state');
            app.cp3.ValueChangedFcn = createCallbackFcn(app, @cp3_Callback, true);
            app.cp3.Enable = 'off';
            app.cp3.Tooltip = 'Red';
            app.cp3.Text = 'CP3';
            app.fp7 = uibutton(app.GUI, 'state');
            app.fp7.ValueChangedFcn = createCallbackFcn(app, @tp7ValueChanged, true);
            app.fp7.Enable = 'off';
            app.fp7.Tooltip = 'Red';
            app.fp7.Text = 'TP7';
            app.cp5 = uibutton(app.GUI, 'state');
            app.cp5.ValueChangedFcn = createCallbackFcn(app, @cp5_Callback, true);
            app.cp5.Enable = 'off';
            app.cp5.Tooltip = 'Red';
            app.cp5.Text = 'CP5';
            app.cp1 = uibutton(app.GUI, 'state');
            app.cp1.ValueChangedFcn = createCallbackFcn(app, @cp1_Callback, true);
            app.cp1.Enable = 'off';
            app.cp1.Tooltip = 'Red';
            app.cp1.Text = 'CP1';
            app.tp9 = uibutton(app.GUI, 'state');
            app.tp9.ValueChangedFcn = createCallbackFcn(app, @tp9_Callback, true);
            app.tp9.Enable = 'off';
            app.tp9.Tooltip = 'Red';
            app.tp9.Text = 'TP9';
            app.cpz = uibutton(app.GUI, 'state');
            app.cpz.ValueChangedFcn = createCallbackFcn(app, @cpz_Callback, true);
            app.cpz.Enable = 'off';
            app.cpz.Tooltip = '[0.31,0.31,0.31]';
            app.cpz.Text = 'CPz';
            app.cp4 = uibutton(app.GUI, 'state');
            app.cp4.ValueChangedFcn = createCallbackFcn(app, @cp4_Callback, true);
            app.cp4.Enable = 'off';
            app.cp4.Tooltip = '[0.04,0.52,0.78]';
            app.cp4.Text = 'CP4';
            app.tp8 = uibutton(app.GUI, 'state');
            app.tp8.ValueChangedFcn = createCallbackFcn(app, @tp8_Callback, true);
            app.tp8.Enable = 'off';
            app.tp8.Tooltip = '[0.04,0.52,0.78]';
            app.tp8.Text = 'TP8';
            app.cp6 = uibutton(app.GUI, 'state');
            app.cp6.ValueChangedFcn = createCallbackFcn(app, @cp6_Callback, true);
            app.cp6.Enable = 'off';
            app.cp6.Tooltip = '[0.04,0.52,0.78]';
            app.cp6.Text = 'CP6';
            app.cp2 = uibutton(app.GUI, 'state');
            app.cp2.ValueChangedFcn = createCallbackFcn(app, @cp2_Callback, true);
            app.cp2.Enable = 'off';
            app.cp2.Tooltip = '[0.04,0.52,0.78]';
            app.cp2.Text = 'CP2';
            app.tp10 = uibutton(app.GUI, 'state');
            app.tp10.ValueChangedFcn = createCallbackFcn(app, @tp10_Callback, true);
            app.tp10.Enable = 'off';
            app.tp10.Tooltip = '[0.04,0.52,0.78]';
            app.tp10.Text = 'TP10';
            app.p5 = uibutton(app.GUI, 'state');
            app.p5.ValueChangedFcn = createCallbackFcn(app, @p5_Callback, true);
            app.p5.Enable = 'off';
            app.p5.Tooltip = 'Red';
            app.p5.Text = 'P5';
            app.p1 = uibutton(app.GUI, 'state');
            app.p1.ValueChangedFcn = createCallbackFcn(app, @p1_Callback, true);
            app.p1.Enable = 'off';
            app.p1.Tooltip = 'Red';
            app.p1.Text = 'P1';
            app.p9 = uibutton(app.GUI, 'state');
            app.p9.ValueChangedFcn = createCallbackFcn(app, @p9_Callback, true);
            app.p9.Enable = 'off';
            app.p9.Tooltip = 'Red';
            app.p9.Text = 'P9';
            app.p8 = uibutton(app.GUI, 'state');
            app.p8.ValueChangedFcn = createCallbackFcn(app, @p8_Callback, true);
            app.p8.Enable = 'off';
            app.p8.Tooltip = '[0.04,0.52,0.78]';
            app.p8.Text = 'P8';
            app.p2 = uibutton(app.GUI, 'state');
            app.p2.ValueChangedFcn = createCallbackFcn(app, @p2_Callback, true);
            app.p2.Enable = 'off';
            app.p2.Tooltip = '[0.04,0.52,0.78]';
            app.p2.Text = 'P2';
            app.p10 = uibutton(app.GUI, 'state');
            app.p10.ValueChangedFcn = createCallbackFcn(app, @p10_Callback, true);
            app.p10.Enable = 'off';
            app.p10.Tooltip = '[0.04,0.52,0.78]';
            app.p10.Text = 'P10';
            app.po3 = uibutton(app.GUI, 'state');
            app.po3.ValueChangedFcn = createCallbackFcn(app, @po3_Callback, true);
            app.po3.Enable = 'off';
            app.po3.Tooltip = 'Red';
            app.po3.Text = 'PO3';
            app.po4 = uibutton(app.GUI, 'state');
            app.po4.ValueChangedFcn = createCallbackFcn(app, @po4_Callback, true);
            app.po4.Enable = 'off';
            app.po4.Tooltip = '[0.04,0.52,0.78]';
            app.po4.Text = 'PO4';
            app.po7 = uibutton(app.GUI, 'state');
            app.po7.ValueChangedFcn = createCallbackFcn(app, @po7_Callback, true);
            app.po7.Enable = 'off';
            app.po7.Tooltip = 'Red';
            app.po7.Text = 'PO7';
            app.po8 = uibutton(app.GUI, 'state');
            app.po8.ValueChangedFcn = createCallbackFcn(app, @po8_Callback, true);
            app.po8.Enable = 'off';
            app.po8.Tooltip = '[0.04,0.52,0.78]';
            app.po8.Text = 'PO8';
            app.iz = uibutton(app.GUI, 'state');
            app.iz.ValueChangedFcn = createCallbackFcn(app, @iz_Callback, true);
            app.iz.Enable = 'off';
            app.iz.Tooltip = '[0,0.5,0]';
            app.iz.Text = 'Iz';
            app.ok = uibutton(app.GUI, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.ok.Icon = 'outline_done_black_48dp.png';
            app.ok.Text = 'Ok';
            app.Panel = uipanel(app.GUI);
            app.Panel.Title = ' ';
            app.header = uilabel(app.GUI);
            app.header.Text = 'Settings for electrode positions';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectrodesDialog_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @ElectrodesDialog_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end