classdef ExportDialog_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                  matlab.ui.Figure
        ExportSettingsPanel  matlab.ui.container.Panel
        boxEEGData           matlab.ui.control.CheckBox
        boxEDAData           matlab.ui.control.CheckBox
        boxHRVData           matlab.ui.control.CheckBox
        boxSignalSpec        matlab.ui.control.CheckBox
        ok                   matlab.ui.control.Button
        Cancel               matlab.ui.control.Button
        allOutputs           matlab.ui.control.Button
        noOutputs            matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        header               matlab.ui.control.Label
    end

    %This is the Settings GUI for the AdAnalyser.

    % The user can set the different settings for the analysis.
    %
    % Author: Tim Kreitzberg
    %

    %function to update GUI accordingly to their values in conf
    methods (Access = private)
        function updateUI(app)
            global conf;
            app.boxEEGData.Value = conf.EEGData;
            app.boxEDAData.Value = conf.EDAData;
            app.boxHRVData.Value = conf.HRVData;
            app.boxSignalSpec.Value = conf.signalSpec;

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ConfigDialog_OpeningFcn(app)
            app.GUI.Visible = 'off';
            movegui(app.GUI,"center");
            app.GUI.Visible = 'on';
            %executes the updateUI to change everything accordingly to conf
            updateUI(app);
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
        %close window
            delete(app);
        end

        % Button pushed function: allOutputs
        function allOutputs_Callback(app, event)
            %turn on all output settings
            app.boxEEGData.Value = 1;
            app.boxEDAData.Value = 1;
            app.boxHRVData.Value = 1;
            app.boxSignalSpec.Value = 1;

        end

        % Button pushed function: noOutputs
        function noOutputs_Callback(app, event)
            %turn off all output settings
            app.boxEEGData.Value = 0;
            app.boxEDAData.Value = 0;
            app.boxHRVData.Value = 0;
            app.boxSignalSpec.Value = 0;

        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            global conf
            % initialize Output flags - update conf
            conf.EEGData = app.boxEEGData.Value;   
            conf.EDAData = app.boxEDAData.Value; 
            conf.HRVData = app.boxHRVData.Value;
            conf.signalSpec = app.boxSignalSpec.Value;
            
            delete(app)
        end

        % Callback function
        function ElectrodeDialogButton_Callback(app, event)
            %Calls electrode dialog
            ElectrodesDialog_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'Settings Dialog';
            app.ExportSettingsPanel = uipanel(app.GUI);
            app.ExportSettingsPanel.Title = 'Export settings';
            app.boxEEGData = uicheckbox(app.ExportSettingsPanel);
            app.boxEEGData.Text = 'EEG Data';
            app.boxEDAData = uicheckbox(app.ExportSettingsPanel);
            app.boxEDAData.Text = 'EDA Data';
            app.boxHRVData = uicheckbox(app.ExportSettingsPanel);
            app.boxHRVData.Text = 'HRV Data';
            app.boxSignalSpec = uicheckbox(app.ExportSettingsPanel);
            app.boxSignalSpec.Text = 'Signal Spectra Data';
            app.ok = uibutton(app.GUI, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Icon = 'outline_done_black_48dp.png';
            app.ok.Text = 'Ok';
            app.Cancel = uibutton(app.GUI, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Icon = 'outline_close_black_48dp.png';
            app.Cancel.Text = 'Cancel';
            app.allOutputs = uibutton(app.GUI, 'push');
            app.allOutputs.ButtonPushedFcn = createCallbackFcn(app, @allOutputs_Callback, true);
            app.allOutputs.Text = 'All';
            app.noOutputs = uibutton(app.GUI, 'push');
            app.noOutputs.ButtonPushedFcn = createCallbackFcn(app, @noOutputs_Callback, true);
            app.noOutputs.Text = 'None';
            app.Panel = uipanel(app.GUI);
            app.Panel.Title = ' ';
            app.header = uilabel(app.GUI);
            app.header.Text = 'Settings for exporting data';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportDialog_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @ConfigDialog_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end