classdef MomentaryFreqDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                    matlab.ui.Figure
        mainGUI                matlab.ui.container.Panel
        FunctionSettings       matlab.ui.container.ButtonGroup
        displaytMean           matlab.ui.control.EditField
        displaytFreq           matlab.ui.control.EditField
        texttMean              matlab.ui.control.Label
        texttFreq              matlab.ui.control.Label
        StimulusandDataPanel   matlab.ui.container.Panel
        StimulusDropDownLabel  matlab.ui.control.Label
        DropStimulus           matlab.ui.control.DropDown
        boxEEG                 matlab.ui.control.CheckBox
        boxEDA                 matlab.ui.control.CheckBox
        boxHRV                 matlab.ui.control.CheckBox
        plot                   matlab.ui.control.Button
        close                  matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        header                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GUI.Visible = 'off';
            movegui(app.GUI,"center");
            app.GUI.Visible = 'on';
            global data
            StimuInts = data.stimuIntDefs;
            count = 1;
            allStimulis = 'all Stimuli';
            
            % get all Stimulus Intervals, which can be analysed
            options{1} = allStimulis;
            for i = 1:length(StimuInts)
                if StimuInts{i}.stimuIntType >= 4
                    options{count+1} = StimuInts{i}.stimuIntDescrp;
                    count = count + 1;
                end
            end
            
            % add options to drop down menu
            app.DropStimulus.Items = options;
            
            % disable Timeframe for first option "all Stimuli"
            app.displaytFreq.Enable = 0;
            app.displaytMean.Enable = 0;
        end

        % Button pushed function: plot
        function plotButtonPushed(app, event)
            global data
            global conf
            plotter = Plotter;
            analyse = AnalyseAction;
            
            fprintf('\nPlot momentary frequency\n')
            
            allStimulis = 'all Stimuli';
            validSubjects = analyse.countValidSubjects(data.subjects);
            numStimuli = length(data.stimuIntDefs);
            numOptions = length(app.DropStimulus.Items);
            
            tMean = str2double(app.displaytMean.Value);
            tFreq = str2double(app.displaytFreq.Value);
           
            message = ['Plotting momentary frequency for ', int2str(validSubjects), ' subject(s)'];
            h = waitbar(0,message);
           
            for i = 1:validSubjects
                if data.subjects{1, i}.isValid == 1 % Filter invalid subjects
                    eegDataperStim = data.subjects{1, i}.eegData.eegValuesSubedBy4;
                    edaDataperStim = data.subjects{1, i}.edaPerStim;
                    hrvDataperStim = data.subjects{1, i}.hrvPerStim;
                    
                    % start loop for a single stimlus
                    if ~strcmp(allStimulis,app.DropStimulus.Value)
                        value = app.DropStimulus.Value;
                        numOptions = 2; 
                    end
                    for k = 2:numOptions
                        % value for all stimulis
                        if strcmp(allStimulis,app.DropStimulus.Value)
                            value = app.DropStimulus.Items(k);
                        end
                        for j = 1:numStimuli
                            if strcmp(data.stimuIntDefs{1, j}.stimuIntDescrp,value)
                                eegData = eegDataperStim{1,j};
                                edaData = edaDataperStim{1,j};
                                hrvData = hrvDataperStim{1,j};
                                stimulus = data.stimuIntDefs{1, j}.stimuIntDescrp;
                                %stimulus marker
                                intervals = data.stimuIntDefs{1, j}.intervals;
                                StimuIntLength = data.stimuIntDefs{1, j}.Stimulength;
                            end
                        end
                        
                        
                        % save settings of checkboxes
                        useData = [app.boxEEG.Value, app.boxEDA.Value, app.boxHRV.Value];
                        subjectName = data.subjects{1, i}.name;
                       
                        plotter.frequency_estimation(tMean, tFreq, subjectName, stimulus, conf, useData, intervals, StimuIntLength, eegData, edaData, hrvData)
                        
                        waitbar(i/length(data.subjects));
                    end
                end
            end
            fprintf('Done\n')
            close(h);
        end

        % Button pushed function: close
        function closeButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DropStimulus
        function DropStimulusValueChanged(app, event)
            value = app.DropStimulus.Value;
            allStimulis = 'all Stimuli';  
            
            if strcmp(value,allStimulis)
                app.displaytFreq.Enable = 0;
                app.displaytMean.Enable = 0;
            else
                app.displaytFreq.Enable = 1;
                app.displaytMean.Enable = 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'MATLAB App';
            app.mainGUI = uipanel(app.GUI);
            app.mainGUI.Title = 'Settings for plot';
            app.FunctionSettings = uibuttongroup(app.mainGUI);
            app.FunctionSettings.Title = 'Function settings';
            app.displaytMean = uieditfield(app.FunctionSettings, 'text');
            app.displaytMean.Value = '0.1';
            app.displaytFreq = uieditfield(app.FunctionSettings, 'text');
            app.displaytFreq.Value = '0.01';
            app.texttMean = uilabel(app.FunctionSettings);
            app.texttMean.Text = 'tMean';
            app.texttFreq = uilabel(app.FunctionSettings);
            app.texttFreq.Text = 'tFreq';
            app.StimulusandDataPanel = uipanel(app.mainGUI);
            app.StimulusandDataPanel.Title = 'Stimulus and Data';
            app.StimulusDropDownLabel = uilabel(app.StimulusandDataPanel);
            app.StimulusDropDownLabel.Text = 'Stimulus     ';
            app.DropStimulus = uidropdown(app.StimulusandDataPanel);
            app.DropStimulus.ValueChangedFcn = createCallbackFcn(app, @DropStimulusValueChanged, true);
            app.boxEEG = uicheckbox(app.StimulusandDataPanel);
            app.boxEEG.Text = 'EEG';
            app.boxEDA = uicheckbox(app.StimulusandDataPanel);
            app.boxEDA.Text = 'EDA';
            app.boxHRV = uicheckbox(app.StimulusandDataPanel);
            app.boxHRV.Text = 'HRV';
            app.plot = uibutton(app.GUI, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plot.Icon = 'baseline_show_chart_black_48dp.png';
            app.plot.Text = 'Plot';
            app.close = uibutton(app.GUI, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.close.Icon = 'outline_close_black_48dp.png';
            app.close.Text = 'Close';
            app.Panel = uipanel(app.GUI);
            app.Panel.Title = ' ';
            app.header = uilabel(app.GUI);
            app.header.Text = ' Momentary Frequency Dialog';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MomentaryFreqDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end